classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HappytoFlowVer17UIFigure  matlab.ui.Figure
        AutoCal                   matlab.ui.control.CheckBox
        Label_3                   matlab.ui.control.Label
        MoveTaskDownButton        matlab.ui.control.Button
        MoveTaskUpButton          matlab.ui.control.Button
        DeleteTaskButton          matlab.ui.control.Button
        AddTaskButton             matlab.ui.control.Button
        TaskTable                 matlab.ui.control.Table
        Cores                     matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        Run                       matlab.ui.control.Button
        QuickPlot                 matlab.ui.control.Button
        openFlowGUI               matlab.ui.control.Button
        Bath                      matlab.ui.control.Button
        Grid                      matlab.ui.control.Button
        PathLabel                 matlab.ui.control.Label
        SelectWorkPathButton      matlab.ui.control.Button
    end

    % This software was developed by Afei Hou and is intended for plotting purposes within the research group.
    % Commercial use by others is prohibited without the developer s permission.
    % Version 1.7
    % 2024-12-13
    properties (Access = public)
        SelectedFolderPath    string  % 添加属性以存储选定的文件夹路径
        CoreCount % 当前计算机核心数
        SelectedCores % 用户选择的核心数
        Delft3DPath % Delft3D主目录路径
        SelectedMDFFile % 用户选择的MDF文件路径
        CalculationPath % 保存用于计算功能的 .mdf 文件路径
        TaskList % 用于存储任务列表数据，格式为 {序号, 文件名, 路径, 状态}
        SelectedTaskRow % 记录选中的任务行

    end


    methods (Access = public)

        function updatePathLabel(app, folderName)
            maxLength = 40; % 最大显示长度


            pathLength = strlength(folderName);
            if pathLength > maxLength
                app.PathLabel.Text = [extractAfter(folderName, pathLength - maxLength)];
            else
                app.PathLabel.Text = folderName;
            end
        end

        function updateTaskTable(app)
            % 按任务序号更新列表
            for i = 1:size(app.TaskList, 1)
                app.TaskList{i, 1} = i;
            end

            % 显示任务表
            app.TaskTable.Data = app.TaskList(:, [1, 2, 3]); % 显示序号、文件名、状态
        end

        function AutoCalValueChanged(app, event) %#ok<INUSD>
            if app.AutoCal.Value
                disp('自动计算已开启');
            else
                disp('自动计算已关闭');
            end
        end

        function executeTask(app, taskPath, mdfFileName, isLastTask)
            % 检查 Delft3D 主路径是否已设置
            delft3DPath = getpref('MyAppSettings', 'Delft3DPath', '');
            if isempty(delft3DPath)
                error('未设置 Delft3D 路径，请重新启动并设置路径。');
            end

            % 确保路径和文件名为字符向量
            taskPath = char(taskPath); % 文件夹路径
            mdfFileName = char(mdfFileName); % 文件名

            % 切换到任务路径
            if ~isfolder(taskPath)
                error('计算路径不存在: %s', taskPath);
            end
            cd(taskPath);

            % 替换路径中的空格为 " "
            delft3DPathFormatted = strrep(delft3DPath, ' ', '" "');

            % 创建 run_parallel.bat
            batFilePath = fullfile(taskPath, 'run_parallel.bat');
            fid = fopen(batFilePath, 'w');
            if fid == -1
                error('无法创建 run_parallel.bat 文件，请检查路径权限。');
            end
            batContent = sprintf(['@echo off\n' ...
                'set NPROC=%d\n' ...
                'call %s\\x64\\dflow2d3d\\scripts\\run_dflow2d3d_parallel.bat %%NPROC%%\n'], ...
                app.SelectedCores, delft3DPathFormatted);
            fprintf(fid, '%s', batContent);
            fclose(fid);

            % 创建 config_d_hydro.xml
            xmlFilePath = fullfile(taskPath, 'config_d_hydro.xml');
            fid = fopen(xmlFilePath, 'w');
            if fid == -1
                error('无法创建 config_d_hydro.xml 文件，请检查路径权限。');
            end
            xmlContent = sprintf(['<?xml version="1.0" encoding="iso-8859-1"?>\n' ...
                '<deltaresHydro xmlns="http://schemas.deltares.nl/deltaresHydro" xmlns:xsi="http://www.w3.org/2001/XMLSchema-instance" xsi:schemaLocation="http://schemas.deltares.nl/deltaresHydro http://content.oss.deltares.nl/schemas/d_hydro-1.00.xsd">\n' ...
                '    <control>\n' ...
                '        <sequence>\n' ...
                '            <start>%s</start>\n' ...
                '        </sequence>\n' ...
                '    </control>\n' ...
                '    <flow2D3D name="%s">\n' ...
                '        <library>flow2d3d</library>\n' ...
                '        <mdfFile>%s.mdf</mdfFile>\n' ...
                '    </flow2D3D>\n' ...
                '</deltaresHydro>'], mdfFileName, mdfFileName, mdfFileName);
            fprintf(fid, '%s', xmlContent);
            fclose(fid);

            % 执行 run_parallel.bat 并在 CMD 窗口中显示
            cmdCommand = sprintf('start cmd /c "%s"', batFilePath); % 使用 CMD 显示计算进程
            disp(['启动批处理文件: ', batFilePath]);
            status = system(cmdCommand);

            % 检查是否成功启动批处理文件
            if status ~= 0
                error('无法启动批处理文件，请检查路径和权限。');
            end

            % 等待批处理启动稳定
            disp('等待批处理文件完全启动...');
            pause(5); % 确保批处理启动并运行相关进程

            % 等待所有 d_hydro.exe 进程结束
            try
                waitForDflowCompletion(app, isLastTask);
            catch ME
                disp(['任务 ', mdfFileName, ' 计算过程中出错: ', ME.message]);
            end

            % 确保任务完成后更新任务状态
            disp(['任务完成: ', mdfFileName]);
        end

        function waitForDflowCompletion(app, isLastTask)
    % 等待所有 d_hydro.exe 进程结束
    disp('等待 d_hydro.exe 计算完成...');
    
    % 定义检测间隔时间（秒）
    intervals = [6, 60, 600, 1800]; % 第6秒、1分钟、10分钟、30分钟检测
    longInterval = 3600; % 后续间隔60分钟
    currentIntervalIndex = 1; % 当前间隔索引

    while true
        % 检测 d_hydro.exe 进程
        [~, result] = system('tasklist /FI "IMAGENAME eq d_hydro.exe"');
        if contains(result, 'd_hydro.exe')
            % 进程仍在运行，打印当前检测间隔
            disp(['d_hydro.exe 仍在运行，下一次检查将在 ', num2str(intervals(currentIntervalIndex)), ' 秒后进行...']);
            pause(intervals(currentIntervalIndex)); % 暂停当前检测间隔

            % 动态更新检测间隔
            if currentIntervalIndex < length(intervals)
                currentIntervalIndex = currentIntervalIndex + 1; % 增加到下一个间隔
            else
                intervals(currentIntervalIndex) = longInterval; % 长期间隔设定为60分钟
            end
        else
            % 进程已结束
            disp('d_hydro.exe 计算完成...');
            break;
        end


    % 如果是最后一个任务，显示完成提示
    if isLastTask
        uialert(app.HappytoFlowVer17UIFigure, '所有任务已处理完毕！', '提示');
    end
end

            % 如果是最后一个任务，弹出完成提示
            if isLastTask
                uialert(app.HappytoFlowVer17UIFigure, '所有任务已处理完毕！', '提示');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% 获取当前可执行文件所在的路径
if isdeployed % 判断是否为打包后的应用
    currentDir = ctfroot; % 获取打包后应用的根目录
else
    currentDir = pwd; % 如果在 MATLAB 中运行，则使用当前工作路径
end

% 检查是否存在 initial.txt 文件
initialFilePath = fullfile(currentDir, 'initial.txt');
if isfile(initialFilePath)
    % 检测到初始化文件，直接当作首次运行
    disp('检测到 initial.txt 文件，将按照首次运行逻辑初始化。');

    % 强制显示使用说明
    choice = uiconfirm(app.HappytoFlowVer17UIFigure, ...
        ['欢迎使用Flow小助手！' newline ...
        '1. 请选择Delft3D软件的版本文件夹，确保路径正确。' newline ...
        '2. 首次设置后，路径将自动保存，下次启动无需重复操作。' newline ...
        '3. 如需使用多核计算，请确保MPI已正确安装和配置。' newline ...
        '祝工作顺利' newline ...
        ' ' newline ...
        '是否需要下次继续显示此说明？'], ...
        '使用说明', ...
        'Options', {'是', '否'}, ...
        'DefaultOption', 1, ...
        'CancelOption', 2);

    % 根据用户选择设置偏好
    if strcmp(choice, '否')
        setpref('MyAppSettings', 'ShowWelcome', false);
    else
        setpref('MyAppSettings', 'ShowWelcome', true);
    end

    % 强制提示用户选择路径
    delft3DPath = uigetdir('', '请选择Delft3D的版本文件夹');
    if delft3DPath ~= 0
        % 用户选择了路径，保存到偏好设置
        setpref('MyAppSettings', 'Delft3DPath', delft3DPath);
        uialert(app.HappytoFlowVer17UIFigure, ...
            ['Delft3D路径已成功设置为：' newline delft3DPath], ...
            '路径设置成功');
    else
        % 用户取消选择路径，显示警告
        uialert(app.HappytoFlowVer17UIFigure, ...
            '未选择路径，应用可能无法正常运行。', ...
            '路径设置警告');
        return; % 返回，阻止进入主界面
    end

    % 删除初始化文件
    delete(initialFilePath);
    disp('initial.txt 文件已删除。');

    % 核心数检测逻辑
    app.CoreCount = feature('numcores'); % 检测当前计算机核心数
    if app.CoreCount > 1
        % 动态生成核心数选项：从1到(核心数-1)
        coreOptions = arrayfun(@num2str, 1:(app.CoreCount - 1), 'UniformOutput', false);
        app.Cores.Items = coreOptions; % 初始化下拉列表选项
        app.Cores.Value = '1'; % 默认值为1核
        app.SelectedCores = 1; % 保存默认核心数
    else
        % 如果核心数小于等于1，仅提供单核选项
        app.Cores.Items = {'1'};
        app.Cores.Value = '1';
        app.SelectedCores = 1;
    end
    return;
end

% 默认逻辑（非首次运行）
% 显示使用说明
if getpref('MyAppSettings', 'ShowWelcome', true)
    choice = uiconfirm(app.HappytoFlowVer17UIFigure, ...
        ['欢迎使用Flow小助手！' newline ...
        '1. 请选择Delft3D软件的版本文件夹，确保路径正确。' newline ...
        '2. 首次设置后，路径将自动保存，下次启动无需重复操作。' newline ...
        '3. 如需使用多核计算，请确保MPI已正确安装和配置。' newline ...
        '祝工作顺利' newline ...
        ' ' newline ...
        '是否需要下次继续显示此说明？'], ...
        '使用说明', ...
        'Options', {'是', '否'}, ...
        'DefaultOption', 1, ...
        'CancelOption', 2);

    % 根据用户选择更新偏好
    if strcmp(choice, '否')
        setpref('MyAppSettings', 'ShowWelcome', false);
    else
        setpref('MyAppSettings', 'ShowWelcome', true);
    end
end

% 检查Delft3D路径
if isempty(getpref('MyAppSettings', 'Delft3DPath', ''))
    % 路径未设置：提示用户选择路径
    delft3DPath = uigetdir('', '请选择Delft3D的版本文件夹');
    if delft3DPath ~= 0
        % 用户选择了路径，保存到偏好设置
        setpref('MyAppSettings', 'Delft3DPath', delft3DPath);
        uialert(app.HappytoFlowVer17UIFigure, ...
            ['Delft3D路径已成功设置为：' newline delft3DPath], ...
            '路径设置成功');
    else
        % 用户取消选择路径，显示警告
        uialert(app.HappytoFlowVer17UIFigure, ...
            '未选择路径，应用可能无法正常运行。', ...
            '路径设置警告');
        return; % 返回，阻止进入主界面
    end
else
    % 路径已设置：加载并提示用户
    delft3DPath = getpref('MyAppSettings', 'Delft3DPath');
    disp(['使用已保存的Delft3D路径：', delft3DPath]);
end

% 核心数检测逻辑
app.CoreCount = feature('numcores'); % 检测当前计算机核心数
if app.CoreCount > 1
    % 动态生成核心数选项：从1到(核心数-1)
    coreOptions = arrayfun(@num2str, 1:(app.CoreCount - 1), 'UniformOutput', false);
    app.Cores.Items = coreOptions; % 初始化下拉列表选项
    app.Cores.Value = '1'; % 默认值为1核
    app.SelectedCores = 1; % 保存默认核心数
else
    % 如果核心数小于等于1，仅提供单核选项
    app.Cores.Items = {'1'};
    app.Cores.Value = '1';
    app.SelectedCores = 1;
end

% 进入主界面
disp('进入GUI主界面...');
        end

        % Button pushed function: SelectWorkPathButton
        function SelectWorkPathButtonPushed(app, event)
            % 弹出选择工作路径对话框
            folderName = uigetdir('', '请选择工作路径');
            if isequal(folderName, 0)
                uialert(app.HappytoFlowVer17UIFigure, '未选择工作路径，程序无法继续运行。', '错误');
                delete(app); % 如果用户取消，关闭程序
                return;
            end
            % 保存工作路径
            app.SelectedFolderPath = folderName;
            cd(app.SelectedFolderPath);
            % 更新工作路径标签
            updatePathLabel(app,folderName);
            % 设置字体为 Helvetica 并取消斜体
            app.PathLabel.FontAngle = 'normal'; % 取消斜体
            app.PathLabel.FontName = 'Helvetica'; % 设置字体为 Helvetica

        end

        % Button pushed function: openFlowGUI
        function openFlowGUIButtonPushed(app, event)
            % 获取用户保存的Delft3D主路径

            delft3DPath = getpref('MyAppSettings', 'Delft3DPath', '');
            if isempty(delft3DPath)
                uialert(app.HappytoFlowVer17UIFigure, '未设置Delft3D路径，请重新启动并设置路径。', '路径错误');
                return;
            end
            % 验证是否选取工作路径
            if isempty(app.SelectedFolderPath)
                uialert(app.HappytoFlowVer17UIFigure, '未选择工作路径，请重试。', '路径错误');
                return;
            else
                cd(app.SelectedFolderPath);
            end

            % 构建完整路径到flow_gui.exe
            exePath = fullfile(delft3DPath, 'x64', 'dflow2d3d', 'bin32', 'flow_gui.exe');

            % 检查文件是否存在
            if isfile(exePath)
                % 调用Flow GUI程序
                status = system(['"', exePath, '" &']);
                if status ~= 0
                    uialert(app.HappytoFlowVer17UIFigure, '无法启动Flow GUI模块，请检查路径或程序文件。', '启动错误');
                end
            else
                uialert(app.HappytoFlowVer17UIFigure, ['找不到文件: ', exePath], '文件未找到');
            end
        end

        % Button pushed function: Grid
        function GridButtonPushed(app, event)


            % 获取用户保存的 Delft3D 主路径
            delft3DPath = getpref('MyAppSettings', 'Delft3DPath', '');
            if isempty(delft3DPath)
                uialert(app.HappytoFlowVer17UIFigure, '未设置 Delft3D 路径，请重新启动并设置路径。', '路径错误');
                return;
            end
            % 验证是否选取工作路径
            if isempty(app.SelectedFolderPath)
                uialert(app.HappytoFlowVer17UIFigure, '未选择工作路径，请重试。', '路径错误');
                return;
            else
                cd(app.SelectedFolderPath);
            end

            % 构建完整路径到 rgfgrid.cmd
            cmdPath = fullfile(delft3DPath, 'x64', 'plugins', 'rgfgrid.cmd');

            % 检查文件是否存在
            if isfile(cmdPath)
                % 调用 rgfgrid.cmd 文件
                status = system(['start /b "" "', cmdPath, '"']);
                if status ~= 0
                    uialert(app.HappytoFlowVer17UIFigure, '无法启动网格处理模块，请检查路径或程序文件。', '启动错误');
                end
            else
                uialert(app.HappytoFlowVer17UIFigure, ['找不到文件: ', cmdPath], '文件未找到');
            end
        end

        % Button pushed function: Bath
        function BathButtonPushed(app, event)

            % 获取用户保存的 Delft3D 主路径
            delft3DPath = getpref('MyAppSettings', 'Delft3DPath', '');
            if isempty(delft3DPath)
                uialert(app.HappytoFlowVer17UIFigure, '未设置 Delft3D 路径，请重新启动并设置路径。', '路径错误');
                return;
            end
            % 验证是否选取工作路径
            if isempty(app.SelectedFolderPath)
                uialert(app.HappytoFlowVer17UIFigure, '未选择工作路径，请重试。', '路径错误');
                return;
            else
                cd(app.SelectedFolderPath);
            end

            % 构建完整路径到 quickin.cmd
            cmdPath = fullfile(delft3DPath, 'x64', 'plugins', 'quickin.cmd');

            % 检查文件是否存在
            if isfile(cmdPath)
                % 调用 quickin.cmd 文件
                status = system(['start /b "" "', cmdPath, '"']);
                if status ~= 0
                    uialert(app.HappytoFlowVer17UIFigure, '无法启动插值功能模块，请检查路径或程序文件。', '启动错误');
                end
            else
                uialert(app.HappytoFlowVer17UIFigure, ['找不到文件: ', cmdPath], '文件未找到');
            end
        end

        % Button pushed function: QuickPlot
        function QuickPlotButtonPushed(app, event)
  % 获取用户保存的 Delft3D 主路径
    delft3DPath = getpref('MyAppSettings', 'Delft3DPath', '');
    quickPlotFolder = fullfile(delft3DPath, 'x64', 'quickplot', 'bin'); % QuickPlot 所在文件夹路径

    % 检查文件夹是否存在
    if ~isfolder(quickPlotFolder)
        uialert(app.HappytoFlowVer17UIFigure, ['找不到文件夹: ', quickPlotFolder], '文件夹未找到');
        return;
    end

    % 打开 QuickPlot 文件夹
    cmdCommand = sprintf('start "" "%s"', quickPlotFolder); % 非阻塞模式打开文件夹
    [status, result] = system(cmdCommand);

    % 检查是否成功打开文件夹
    if status ~= 0
        disp(['打开 QuickPlot 文件夹失败: ', result]);
        uialert(app.HappytoFlowVer17UIFigure, '无法打开 QuickPlot 文件夹，请检查路径和权限。', '错误');
    else
        disp(['QuickPlot 文件夹已打开: ', quickPlotFolder]);
    end
        end

        % Value changed function: Cores
        function CoresValueChanged(app, event)
            selectedValue = app.Cores.Value;
            app.SelectedCores = str2double(selectedValue); % 将选择的核心数保存到属性中
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            if isempty(app.TaskList)
                uialert(app.HappytoFlowVer17UIFigure, '任务列表为空，请添加任务后再开始计算。', '提示');
                return;
            end

            % 遍历任务列表
            for i = 1:size(app.TaskList, 1)
                % 如果未勾选 "自动计算所有任务"，只计算第一个待计算任务
                if ~app.AutoCal.Value && (~strcmp(app.TaskList{i, 3}, '待计算'))
                    continue;
                end

                % 跳过已完成或正在计算的任务
                if strcmp(app.TaskList{i, 3}, '已完成') || strcmp(app.TaskList{i, 3}, '正在计算')
                    continue;
                end

                % 更新任务状态为“正在计算”
                app.TaskList{i, 3} = '正在计算';
                updateTaskTable(app); % 实时更新任务列表

                try
                    % 获取任务路径和文件名
                    taskPath = app.TaskList{i, 4};
                    mdfFileName = app.TaskList{i, 2};

                    % 执行任务
                    executeTask(app, taskPath, mdfFileName);

                    % 任务完成，更新状态
                    app.TaskList{i, 3} = '已完成';
                catch ME
                    % 捕获错误并更新状态
                    app.TaskList{i, 3} = '计算失败';
                    disp(['任务 ', num2str(i), ' 出错: ', ME.message]);
                end

                % 实时更新任务表
                updateTaskTable(app);

                % 检查复选框状态
                if ~app.AutoCal.Value
                    disp('计划任务已被用户取消，停止后续任务...');
                    break; % 停止后续任务
                end
            end

            % 提示任务完成
            uialert(app.HappytoFlowVer17UIFigure, '所有任务已处理完毕或无人值守已被取消。', '提示');
        end

        % Button pushed function: AddTaskButton
        function AddTaskButtonPushed(app, event)
            % 多文件选择对话框
            [fileNames, filePath] = uigetfile('*.mdf', '选择一个或多个 MDF 文件', 'MultiSelect', 'on');
            if isequal(fileNames, 0)
                return; % 用户取消选择
            end

            % 如果是单个文件，转换为单元格数组
            if ischar(fileNames)
                fileNames = {fileNames};
            end

            % 遍历选择的文件
            for i = 1:length(fileNames)
                % 提取文件名和完整路径
                [~, fileName, ~] = fileparts(fileNames{i});
                folderPath = filePath; % 文件夹路径

                % 检查是否重复
                isDuplicate = false;
                if ~isempty(app.TaskList)
                    isDuplicate = any(cellfun(@(x, y) strcmp(x, fileName) && strcmp(y, folderPath), ...
                        app.TaskList(:, 2), app.TaskList(:, 4)));
                end

                if isDuplicate
                    % 如果重复，提示用户
                    existingIndex = find(cellfun(@(x, y) strcmp(x, fileName) && strcmp(y, folderPath), ...
                        app.TaskList(:, 2), app.TaskList(:, 4)));
                    uialert(app.HappytoFlowVer17UIFigure, ...
                        sprintf('任务 "%s" 已存在任务列表中，序号为 %d，无法重复添加。', fileName, existingIndex), ...
                        '重复任务');
                else
                    % 添加新任务到任务列表
                    newTask = {size(app.TaskList, 1) + 1, fileName, '待计算', folderPath};
                    app.TaskList = [app.TaskList; newTask];
                end
            end

            % 更新任务表
            updateTaskTable(app);
        end

        % Button pushed function: DeleteTaskButton
        function DeleteTaskButtonPushed(app, event)
            selectedRow = app.SelectedTaskRow; % 获取用户选中的行号

            % 校验是否有选中行
            if isempty(selectedRow)
                uialert(app.HappytoFlowVer17UIFigure, '请选择一行任务以删除。', '提示');
                return;
            end

            % 检查选中的行是否有效
            if selectedRow > size(app.TaskList, 1) || isempty(app.TaskList{selectedRow, 2})
                uialert(app.HappytoFlowVer17UIFigure, '选中的行无效，请选择有效的任务行。', '提示');
                return;
            end

            % 删除选中任务
            app.TaskList(selectedRow, :) = [];
            updateTaskTable(app); % 更新任务表

        end

        % Button pushed function: MoveTaskUpButton
        function MoveTaskUpButtonPushed(app, event)
            selectedRow = app.SelectedTaskRow; % 获取选中的行索引
            if isempty(selectedRow)
                uialert(app.HappytoFlowVer17UIFigure, '未选中任何任务', '提示');
                return;
            end

            % 检查选中行是否有效（避免空行被操作）
            if isempty(app.TaskList{selectedRow, 2})
                uialert(app.HappytoFlowVer17UIFigure, '选中的任务无效，请选择有效的任务行。', '提示');
                return;
            end

            % 检查是否是第一行任务
            if selectedRow == 1
                uialert(app.HappytoFlowVer17UIFigure, '无法上移第一行任务', '提示');
                return;
            end

            % 检查任务状态是否为“正在计算”或“已完成”或“计算失败”
            if any(strcmp(app.TaskList{selectedRow, 3}, {'正在计算', '已完成', '计算失败'}))
                uialert(app.HappytoFlowVer17UIFigure, '无法调整已完成、计算失败或正在计算的任务顺序。', '提示');
                return;
            end

            % 检查上一行任务状态
            if any(strcmp(app.TaskList{selectedRow - 1, 3}, {'正在计算', '已完成', '计算失败'}))
                uialert(app.HappytoFlowVer17UIFigure, '无法与已完成、计算失败或正在计算的任务交换顺序。', '提示');
                return;
            end

            % 调整任务顺序
            app.TaskList([selectedRow - 1, selectedRow], :) = app.TaskList([selectedRow, selectedRow - 1], :);

            % 更新任务序号并刷新表格
            updateTaskTable(app);

            % 更新选中行索引
            app.SelectedTaskRow = selectedRow - 1;
        end

        % Button pushed function: MoveTaskDownButton
        function MoveTaskDownButtonPushed(app, event)
            selectedRow = app.SelectedTaskRow; % 获取选中的行索引
            if isempty(selectedRow)
                uialert(app.HappytoFlowVer17UIFigure, '未选中任何任务', '提示');
                return;
            end

            % 检查选中行是否有效（避免空行被操作）
            if isempty(app.TaskList{selectedRow, 2})
                uialert(app.HappytoFlowVer17UIFigure, '选中的任务无效，请选择有效的任务行。', '提示');
                return;
            end

            % 检查是否是最后一行任务
            if selectedRow == size(app.TaskList, 1)
                uialert(app.HappytoFlowVer17UIFigure, '无法下移最后一行任务', '提示');
                return;
            end

            % 检查当前任务状态是否为“正在计算”或“已完成”或“计算失败”
            if any(strcmp(app.TaskList{selectedRow, 3}, {'正在计算', '已完成', '计算失败'}))
                uialert(app.HappytoFlowVer17UIFigure, '无法调整已完成、计算失败或正在计算的任务顺序。', '提示');
                return;
            end

            % 检查下一行任务状态
            if any(strcmp(app.TaskList{selectedRow + 1, 3}, {'正在计算', '已完成', '计算失败'}))
                uialert(app.HappytoFlowVer17UIFigure, '无法与已完成、计算失败或正在计算的任务交换顺序。', '提示');
                return;
            end

            % 调整任务顺序
            app.TaskList([selectedRow, selectedRow + 1], :) = app.TaskList([selectedRow + 1, selectedRow], :);

            % 更新任务序号并刷新表格
            updateTaskTable(app);

            % 更新选中行索引
            app.SelectedTaskRow = selectedRow + 1;
        end

        % Cell selection callback: TaskTable
        function TaskTableCellSelection(app, event)
            % 检查是否有选中内容
            if ~isempty(event.Indices)
                app.SelectedTaskRow = event.Indices(1); % 保存选中的行索引
            else
                app.SelectedTaskRow = []; % 清空选中行
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HappytoFlowVer17UIFigure = uifigure('Visible', 'off');
            app.HappytoFlowVer17UIFigure.Name = 'Happy to Flow (Ver.1.7)';
            app.HappytoFlowVer17UIFigure.Icon = fullfile(pathToMLAPP, 'ic.jpg');
            app.SelectWorkPathButton = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.SelectWorkPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkPathButtonPushed, true);
            app.SelectWorkPathButton.Text = '选择工作路径';
            app.PathLabel = uilabel(app.HappytoFlowVer17UIFigure);
            app.PathLabel.Text = 'Afei Hou, Hohai University, a.f.hou@hhu.edu.cn';
            app.Grid = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.Grid.ButtonPushedFcn = createCallbackFcn(app, @GridButtonPushed, true);
            app.Grid.Text = '网格';
            app.Bath = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.Bath.ButtonPushedFcn = createCallbackFcn(app, @BathButtonPushed, true);
            app.Bath.Text = '插值';
            app.openFlowGUI = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.openFlowGUI.ButtonPushedFcn = createCallbackFcn(app, @openFlowGUIButtonPushed, true);
            app.openFlowGUI.Text = '水动力';
            app.QuickPlot = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.QuickPlot.ButtonPushedFcn = createCallbackFcn(app, @QuickPlotButtonPushed, true);
            app.QuickPlot.Text = '后处理';
            app.Run = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = '计算';
            app.Label_2 = uilabel(app.HappytoFlowVer17UIFigure);
            app.Label_2.Text = '核心数';
            app.Cores = uidropdown(app.HappytoFlowVer17UIFigure);
            app.Cores.Items = {'1'};
            app.Cores.ValueChangedFcn = createCallbackFcn(app, @CoresValueChanged, true);
            app.Cores.Value = '1';
            app.TaskTable = uitable(app.HappytoFlowVer17UIFigure);
            app.TaskTable.ColumnName = {'序号'; '文件名'; '状态'};
            app.TaskTable.RowName = {};
            app.TaskTable.CellSelectionCallback = createCallbackFcn(app, @TaskTableCellSelection, true);
            app.AddTaskButton = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.AddTaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddTaskButtonPushed, true);
            app.AddTaskButton.Text = '➕';
            app.DeleteTaskButton = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.DeleteTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTaskButtonPushed, true);
            app.DeleteTaskButton.Text = '➖';
            app.MoveTaskUpButton = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.MoveTaskUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskUpButtonPushed, true);
            app.MoveTaskUpButton.Text = '↑';
            app.MoveTaskDownButton = uibutton(app.HappytoFlowVer17UIFigure, 'push');
            app.MoveTaskDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveTaskDownButtonPushed, true);
            app.MoveTaskDownButton.Text = '↓';
            app.Label_3 = uilabel(app.HappytoFlowVer17UIFigure);
            app.Label_3.Text = '任务列表';
            app.AutoCal = uicheckbox(app.HappytoFlowVer17UIFigure);
            app.AutoCal.Text = '无人值守';
            app.AutoCal.Value = true;
            % Show the figure after all components are created
            app.HappytoFlowVer17UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HappytoFlowVer17UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HappytoFlowVer17UIFigure)
        end
    end
end